classdef Dataset_Processor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RadarDatasetProcessorLabel      matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        Tab2                            matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        Tab3                            matlab.ui.container.Tab
        UIAxes3                         matlab.ui.control.UIAxes
        Tab4                            matlab.ui.container.Tab
        UIAxes4                         matlab.ui.control.UIAxes
        TransmissionParametersPanel     matlab.ui.container.Panel
        RecordingtimeEditField          matlab.ui.control.NumericEditField
        RecordingtimeEditFieldLabel     matlab.ui.control.Label
        RecordButton                    matlab.ui.control.Button
        SamplingFrequencyEditField      matlab.ui.control.NumericEditField
        SamplingFrequencyEditFieldLabel  matlab.ui.control.Label
        CarrierFrequencyEditField       matlab.ui.control.NumericEditField
        CarrierFrequencyEditFieldLabel  matlab.ui.control.Label
        SpectrogramParametersPanel      matlab.ui.container.Panel
        ClimsEditField                  matlab.ui.control.NumericEditField
        ClimsEditFieldLabel             matlab.ui.control.Label
        WindowTypeListBox               matlab.ui.control.ListBox
        WindowTypeListBoxLabel          matlab.ui.control.Label
        FFTLengthEditField              matlab.ui.control.NumericEditField
        FFTLengthEditFieldLabel         matlab.ui.control.Label
        OverlapEditField                matlab.ui.control.NumericEditField
        OverlapEditFieldLabel           matlab.ui.control.Label
        WindowLengthEditField           matlab.ui.control.NumericEditField
        WindowLengthEditFieldLabel      matlab.ui.control.Label
        ComputeButton                   matlab.ui.control.Button
        DatasetSelectionPanel           matlab.ui.container.Panel
        SelectFileButton                matlab.ui.control.Button
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = private)
        %{
        TxSignal
        RX_signal
        Fs
        t
        Fc_Hz
        SpeedSoundWave_ms
        baseband_signal
        %}
        TxRxFigure
        SpectrogramFigure
        SpectrogramKmphFigure
        SpectrogramMsFigure
        LoadedVars; 
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)

            app.SpeedSoundWave_ms = 343;             % [m/s]  -> Speed of sound wave
            app.Fc_Hz = app.CarrierFrequencyEditField.Value;                      % [Hz]   -> Carrier frequency
            TimeDuration_s = app.RecordingtimeEditField.Value;                  % [s]    -> Duration of signal transmission and reception
            app.Fs = app.SamplingFrequencyEditField.Value; 
            lambda = app.SpeedSoundWave_ms/app.Fc_Hz;

            %% Generate the transmit signal
Ts = 1/app.Fs;                           % Sampling period
app.t = 0:Ts:(TimeDuration_s);         
app.TxSignal = sin(2 * pi * app.Fc_Hz * app.t);
soundsc(app.TxSignal, app.Fs, 24);  % Transmit the signal

%% Record received samples from the microphone
RecLength_samples = length(app.TxSignal);
RecLength_s = RecLength_samples * Ts; 
recObj = audiorecorder(app.Fs, 24, 1);
recordblocking(recObj, RecLength_s);  % Record audio for a fixed number of seconds
app.RX_signal = getaudiodata(recObj).';     % Store recorded audio signal in double-precision array
clear recObj;

if ~exist('Figures3', 'dir')
    mkdir('Figures3');
end
if ~exist('Figures3\TransmitReceiveSignals', 'dir')
    mkdir('Figures3\TransmitReceiveSignals');
end
if ~exist('Figures3\Spectrograms', 'dir')
    mkdir('Figures3\Spectrograms');
end
if ~exist('Workspaces3', 'dir')
    mkdir('Workspaces3');
end

figure; axes('fontsize', 12);
subplot(2,1,1);
plot(app.t, app.TxSignal); 
xlabel('Time (s)', 'fontsize', 12);
ylabel('Amplitude (linear)', 'fontsize', 12);
title('Transmit Signal', 'fontsize', 12);
grid on;

subplot(2,1,2);
plot(app.t, app.RX_signal);
xlabel('Time (s)', 'fontsize', 12);
ylabel('Amplitude (linear)', 'fontsize', 12);
title('Received Signal (with Doppler Shift)', 'fontsize', 12);
grid on;


        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
TimeAxis_s = app.LoadedVars.TimeAxis_s;  
t = app.LoadedVars.t;
           
            
            %% Notch and Bandpass Filter
%{
lowFreq = 20000;  
highFreq = 60000;
fs = app.Fs;
%[b_bp, a_bp] = butter(6, [lowFreq highFreq]/(fs/2), 'bandpass');
%RX_signal_bp = filtfilt(b, a, app.RX_signal);

w0 = app.Fc_Hz/(app.Fs/2);  
bw = 0.001;     
[b_notch, a_notch] = iirnotch(w0, bw); 
RX_signal_filtered = filtfilt(b_notch, a_notch, app.RX_signal);

%% Quadrature Demodulation - used to downmix the signal to baseband 
cos_component = cos(2 * pi * app.Fc_Hz * app.t);
sin_component = -sin(2 * pi * app.Fc_Hz * app.t);
I_signal = RX_signal_filtered .* cos_component;
Q_signal = RX_signal_filtered .* sin_component;

fcutoff = 20000;
[b, a] = butter(5, fcutoff / (app.Fs / 2), 'low');

I_baseband = filter(b, a, I_signal);
Q_baseband = filter(b, a, Q_signal);
baseband_signal = I_baseband + (1i * Q_baseband);
%}


%% Spectrogram analysis
% Parameters for spectrogram
W = app.WindowLengthEditField.Value; % Frame length 
O = (app.OverlapEditField.Value) * W; %  overlap
nfft = app.FFTLengthEditField.Value; % FFT length
if app.WindowTypeListBox.Value == "Hamming"
    w = hamming(W);  % Create Hamming window of length W
   % app.Label.Text = app.WindowTypeListBox.Value;
elseif app.WindowTypeListBox.Value == "Rectangular"
    w = ones(W, 1);  % Create Rectangular window of length W (all ones)
end

Fc_Hz = app.LoadedVars.Fc_Hz;
Fs = app.LoadedVars.Fs;
w0 = Fc_Hz/(Fs/2);  
bw = 0.001;     
[b_notch, a_notch] = iirnotch(w0, bw); 
RX_signal = app.LoadedVars.RX_signal;
RX_signal_filtered = filtfilt(b_notch, a_notch, RX_signal);

%% Quadrature Demodulation - used to downmix the signal to baseband 
cos_component = cos(2 * pi * Fc_Hz * t);
sin_component = -sin(2 * pi * Fc_Hz * t);
I_signal = RX_signal_filtered .* cos_component;
Q_signal = RX_signal_filtered .* sin_component;

fcutoff = 20000;
[b, a] = butter(5, fcutoff / (Fs / 2), 'low');

I_baseband = filter(b, a, I_signal);
Q_baseband = filter(b, a, Q_signal);
baseband_signal = I_baseband + (1i * Q_baseband);

%baseband_signal = app.LoadedVars.baseband_signal;
Fs = app.LoadedVars.Fs;
[S, TimeAxis_s, FrequencyAxis_Hz] = JoachimSpectrogram(baseband_signal, w, W, O, Fs, nfft);
%FrequencyAxis_Hz = app.LoadedVars.FrequencyAxis_Hz;
maxFreq = 10000;
minFreq = -10000;
freq_idx = find((FrequencyAxis_Hz <= maxFreq) & (FrequencyAxis_Hz >= minFreq));
FreqVectorOfInterest = FrequencyAxis_Hz(freq_idx);
%S = app.LoadedVars.S;
F_OfInterest = S(freq_idx, :);
F_OfInterestToPlot = abs(F_OfInterest)/max(max(abs(F_OfInterest))); % normalise plot

%{
% Spectrogram plot
clims = [-50 0];
figure;
%imagesc(TimeAxis_s, FrequencyAxis_Hz/1e3, 20*log10(abs(S)),clims);
TimeAxis_s = app.LoadedVars.TimeAxis_s;
imagesc(TimeAxis_s,FreqVectorOfInterest,20*log10(F_OfInterestToPlot), clims);
colorbar;
xlabel('Time (s)');
ylabel('Frequency (Hz)');
title('Spectrogram of Received Signal');
axis xy;
%}

% Create Spectrogram plot in Tab2
clims = [-50 0];
%TimeAxis_s = app.LoadedVars.TimeAxis_s;
imagesc(app.UIAxes2, TimeAxis_s, FreqVectorOfInterest, 20*log10(F_OfInterestToPlot), clims); % Plot in Tab2's UIAxes
colorbar(app.UIAxes2);
%colormap(app.UIAxes2, 'jet');
xlabel(app.UIAxes2, 'Time (s)');
ylabel(app.UIAxes2, 'Frequency (Hz)');
title(app.UIAxes2, 'Spectrogram of Received Signal');
axis(app.UIAxes2, 'xy');

%saveas(gcf, ['Figures3\Spectrograms\Spectrogram_' timestamp '_' num2str(run_id) '.png']);
%savefig(['Figures3\Spectrograms\Spectrogram_' timestamp '_' num2str(run_id) '.fig']);

maxSpeed_m_s = 15;
minSpeed_m_s = -15; 

maxSpeed_km_hr = 50;
minSpeed_km_per_hr = -50; 
lamda = app.LoadedVars.lamda;
speed_m_per_sec = FrequencyAxis_Hz*lamda/2;
speed_km_per_hr = speed_m_per_sec*(60*60/1000);
speed_km_per_hr_Idx = find((speed_km_per_hr <= maxSpeed_km_hr) & (speed_km_per_hr >= minSpeed_km_per_hr));
SpeedVectorOfInterest = speed_km_per_hr(speed_km_per_hr_Idx);

S_OfInterest = S(speed_km_per_hr_Idx, :);
S_OfInterestToPlot = abs(S_OfInterest)/max(max(abs(S_OfInterest))); % normalise plot

speed_m_per_sec = FrequencyAxis_Hz*lamda/2;
speed_m_per_sec_Idx = find((speed_m_per_sec <= maxSpeed_m_s) & (speed_m_per_sec >= minSpeed_m_s));

SpeedVectorOfInterest_m_s = speed_m_per_sec(speed_km_per_hr_Idx);
S_OfInterest_m_s = S(speed_m_per_sec_Idx, :);
S_OfInterestToPlot_m_s = abs(S_OfInterest_m_s)/max(max(abs(S_OfInterest_m_s))); % normalise plot

% Plot of spectrogram in kilometres per hour (Tab3)
%{
imagesc(app.UIAxes3, TimeAxis_s, SpeedVectorOfInterest, 20*log10(S_OfInterestToPlot), clims); % Plot in Tab3's UIAxes
xlabel(app.UIAxes3, 'Time (s)');
ylabel(app.UIAxes3, 'Speed (km/hr)');
title(app.UIAxes3, 'Spectrogram: zoomed in (km/hr)');
grid(app.UIAxes3, 'on');
colorbar(app.UIAxes3);
colormap(app.UIAxes3, 'jet');
axis(app.UIAxes3, 'xy');
%}
% Create Spectrogram plot in Tab3
clims = [-50 0];
imagesc(app.UIAxes3, TimeAxis_s, SpeedVectorOfInterest, 20*log10(S_OfInterestToPlot), clims); % Plot in Tab2's UIAxes
colorbar(app.UIAxes3);
%colormap(app.UIAxes3, 'jet');
xlabel(app.UIAxes3, 'Time (s)');
ylabel(app.UIAxes3, 'Speed (km/hr)');
title(app.UIAxes3, 'Spectrogram: zoomed in (km/hr)');
axis(app.UIAxes3, 'xy');


%{
imagesc(app.UIAxes4, TimeAxis_s, SpeedVectorOfInterest_m_s, 20*log10(S_OfInterestToPlot_m_s), clims); % Plot in Tab4's UIAxes
xlabel(app.UIAxes4, 'Time (s)');
ylabel(app.UIAxes4, 'Speed (m/s)');
title(app.UIAxes4, 'Spectrogram: zoomed in (m/s)');
grid(app.UIAxes4, 'on');
colorbar(app.UIAxes4);
colormap(app.UIAxes4, 'jet');
axis(app.UIAxes4, 'xy');
%}
% Plot of spectrogram in meters per second (Tab4)
clims = [-50 0];
imagesc(app.UIAxes4, TimeAxis_s, SpeedVectorOfInterest_m_s, 20*log10(S_OfInterestToPlot_m_s), clims); % Plot in Tab2's UIAxes
colorbar(app.UIAxes4);
%colormap(app.UIAxes4, 'jet');
xlabel(app.UIAxes4, 'Time (s)');
ylabel(app.UIAxes4, 'Speed (m/s)');
title(app.UIAxes4, 'Spectrogram: zoomed in (m/s)');
axis(app.UIAxes4, 'xy');

%save(['Workspaces3\SpectrogramWorkspace_' timestamp '_' num2str(run_id) '.mat']); 

%% Spectrogram Function
function [S, TimeAxis_s, FrequencyAxis_Hz] = JoachimSpectrogram(x, w, W, O, fs, nfft)

 x = x(:);
% Number of frames
 N_f = floor((length(x)-W)/O)+1;
 S = zeros(nfft, N_f);

% Time axis
 TimeAxis_s = ((0:N_f-1) * O + W/2) / fs;

% Frequency axis
if mod(nfft, 2) == 0
 FrequencyAxis_Hz = (-nfft/2:nfft/2-1) * fs / nfft;
else
 FrequencyAxis_Hz = (-(nfft-1)/2:(nfft-1)/2) * fs / nfft;
end

% Computing the spectrogram
for k = 1:N_f
 StartIdx = (k - 1) * O + 1;
 StopIdx = StartIdx + W - 1;
 frame = x(StartIdx : StopIdx);
 frame = frame .* w;
 fft_frame = fftshift(fft(frame, nfft));
 S(:, k) = fft_frame;
end
end

%% Function to get new identifier to store data as a unique entry 
function id = getNextRunID()
    if exist('last_run_id.mat', 'file')
        load('last_run_id.mat', 'last_id');
        id = last_id + 1;
    else
        id = 1;
    end
    last_id = id;
    save('last_run_id.mat', 'last_id');
end
%}
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
    [filename, pathname] = uigetfile('*.mat', 'Select a MAT file');
    
    if isequal(filename, 0)
        return;  % If no file selected, return
    end
    
    fullpath = fullfile(pathname, filename);
    
    try
        % Used to load all variables from the .mat file
        app.LoadedVars = load(fullpath);  % Store the loaded variables in LoadedVars property
        
        varNames = fieldnames(app.LoadedVars);
        disp('Variables loaded:');
        disp(varNames);
        
       
        msgbox('File loaded successfully!', 'Success');
    catch ME
        msgbox(['Error loading file: ' ME.message], 'Error', 'error');
    end

        end

        % Callback function: not associated with a component
        function CreatePlotsButtonPushed(app, event)
            try
        % Clear any existing axes in tabs
        for i = 1:length(app.TabGroup.Children)
            clf(app.TabGroup.Children(i));
        end

        % Calculate lambda
        lamda = app.SpeedSoundWave_ms/app.Fc_Hz;

        % Create timestamp
        %timestamp = datestr(now, 'yyyymmdd_HHMMSS');
        %run_id = app.getNextRunID();

        % Create two axes in Tab1 for Tx and Rx signals
        ax1 = uiaxes(app.Tab, 'Position', [10 200 app.Tab.Position(3)-20 150]);
        ax2 = uiaxes(app.Tab, 'Position', [10 20 app.Tab.Position(3)-20 150]);

        % Plot Transmit Signal
        plot(ax1, app.t, app.TxSignal); 
        xlabel(ax1, 'Time (s)', 'FontSize', 12);
        ylabel(ax1, 'Amplitude (linear)', 'FontSize', 12);
        title(ax1, 'Transmit Signal', 'FontSize', 12);
        grid(ax1, 'on');

        % Plot Receive Signal
        plot(ax2, app.t, app.RX_signal);
        xlabel(ax2, 'Time (s)', 'FontSize', 12);
        ylabel(ax2, 'Amplitude (linear)', 'FontSize', 12);
        title(ax2, 'Received Signal (with Doppler Shift)', 'FontSize', 12);
        grid(ax2, 'on');

        %% Notch filter 
        lowFreq = 20000;  
        highFreq = 60000;
        [b_bp, a_bp] = butter(6, [lowFreq highFreq]/(app.Fs/2), 'bandpass');
        RX_signal_bp = filtfilt(b_bp, a_bp, app.RX_signal);

        w0 = app.Fc_Hz/(app.Fs/2);  
        bw = 0.001;     
        [b_notch, a_notch] = iirnotch(w0, bw); 
        RX_signal_filtered = filtfilt(b_notch, a_notch, app.RX_signal);

        %% Quadrature Demodulation
        cos_component = cos(2 * pi * app.Fc_Hz * app.t);
        sin_component = -sin(2 * pi * app.Fc_Hz * app.t);
        I_signal = RX_signal_filtered .* cos_component;
        Q_signal = RX_signal_filtered .* sin_component;

        fcutoff = 20000;
        [b, a] = butter(5, fcutoff / (app.Fs / 2), 'low');

        I_baseband = filter(b, a, I_signal);
        Q_baseband = filter(b, a, Q_signal);
        baseband_signal = I_baseband + (1i * Q_baseband);

        %% Spectrogram analysis
        W = 4096; % Frame length 
        O = 0.5 * W; % overlap
        nfft = 4096; % FFT length
        w = hamming(W);

        [S, TimeAxis_s, FrequencyAxis_Hz] = app.JoachimSpectrogram(app.baseband_signal, w, W, O, app.Fs, nfft);

        maxFreq = 10000;
        minFreq = -10000;
        freq_idx = find((FrequencyAxis_Hz <= maxFreq) & (FrequencyAxis_Hz >= minFreq));
        FreqVectorOfInterest = FrequencyAxis_Hz(freq_idx);
        F_OfInterest = S(freq_idx, :);
        F_OfInterestToPlot = abs(F_OfInterest)/max(max(abs(F_OfInterest)));

        % Create Spectrogram plot in Tab2
        clims = [-40 0];
        app.SpectrogramFigure = uiaxes(app.Tab2, 'Position', [10 10 app.Tab2.Position(3)-20 app.Tab2.Position(4)-20]);
        imagesc(app.SpectrogramFigure, TimeAxis_s, FreqVectorOfInterest, ...
            20*log10(F_OfInterestToPlot), clims);
        colorbar(app.SpectrogramFigure);
        xlabel(app.SpectrogramFigure, 'Time (s)');
        ylabel(app.SpectrogramFigure, 'Frequency (Hz)');
        title(app.SpectrogramFigure, 'Spectrogram of Received Signal');
        colormap(app.SpectrogramFigure, 'jet');
        axis(app.SpectrogramFigure, 'xy');

        %% Speed calculations
        maxSpeed_km_hr = 50;
        minSpeed_km_per_hr = -50; 
        speed_m_per_sec = FrequencyAxis_Hz*lamda/2;
        speed_km_per_hr = speed_m_per_sec*(60*60/1000);
        speed_km_per_hr_Idx = find((speed_km_per_hr <= maxSpeed_km_hr) & ...
            (speed_km_per_hr >= minSpeed_km_per_hr));
        SpeedVectorOfInterest = speed_km_per_hr(speed_km_per_hr_Idx);

        S_OfInterest = S(speed_km_per_hr_Idx, :);
        S_OfInterestToPlot = abs(S_OfInterest)/max(max(abs(S_OfInterest)));

        maxSpeed_m_s = 15;
        minSpeed_m_s = -15; 
        speed_m_per_sec_Idx = find((speed_m_per_sec <= maxSpeed_m_s) & ...
            (speed_m_per_sec >= minSpeed_m_s));
        SpeedVectorOfInterest_m_s = speed_m_per_sec(speed_km_per_hr_Idx);
        S_OfInterest_m_s = S(speed_m_per_sec_Idx, :);
        S_OfInterestToPlot_m_s = abs(S_OfInterest_m_s)/max(max(abs(S_OfInterest_m_s)));

        % Create Spectrogram km/hr plot in Tab3
        app.SpectrogramKmphFigure = uiaxes(app.Tab3, 'Position', [10 10 app.Tab3.Position(3)-20 app.Tab3.Position(4)-20]);
        imagesc(app.SpectrogramKmphFigure, app.t, SpeedVectorOfInterest, ...
            20*log10(S_OfInterestToPlot), clims);
        xlabel(app.SpectrogramKmphFigure, 'Time (s)');
        ylabel(app.SpectrogramKmphFigure, 'Speed (km/hr)');
        title(app.SpectrogramKmphFigure, 'Spectrogram: zoomed in (km/hr)');
        grid(app.SpectrogramKmphFigure, 'on');
        colorbar(app.SpectrogramKmphFigure);
        colormap(app.SpectrogramKmphFigure, 'jet');
        axis(app.SpectrogramKmphFigure, 'xy');

        % Create Spectrogram m/s plot in Tab4
        app.SpectrogramMsFigure = uiaxes(app.Tab4, 'Position', [10 10 app.Tab4.Position(3)-20 app.Tab4.Position(4)-20]);
        imagesc(app.SpectrogramMsFigure, app.t, SpeedVectorOfInterest_m_s, ...
            20*log10(S_OfInterestToPlot_m_s), clims);
        xlabel(app.SpectrogramMsFigure, 'Time (s)');
        ylabel(app.SpectrogramMsFigure, 'Speed (m/s)');
        title(app.SpectrogramMsFigure, 'Spectrogram: zoomed in (m/s)');
        grid(app.SpectrogramMsFigure, 'on');
        colorbar(app.SpectrogramMsFigure);
        colormap(app.SpectrogramMsFigure, 'jet');
        axis(app.SpectrogramMsFigure, 'xy');

    catch ME
        % Show error message if plotting fails
        msgbox(['Error creating plots: ' ME.message], 'Error', 'error');
        disp(getReport(ME));
            end    
    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'uct.png');
            app.DatasetSelectionPanel = uipanel(app.UIFigure);
            app.DatasetSelectionPanel.Title = 'Dataset Selection';
            app.SelectFileButton = uibutton(app.DatasetSelectionPanel, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.SpectrogramParametersPanel = uipanel(app.UIFigure);
            app.SpectrogramParametersPanel.Title = 'Spectrogram Parameters';
            app.ComputeButton = uibutton(app.SpectrogramParametersPanel, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.WindowLengthEditFieldLabel = uilabel(app.SpectrogramParametersPanel);
            app.WindowLengthEditFieldLabel.Text = 'Window Length';
            app.WindowLengthEditField = uieditfield(app.SpectrogramParametersPanel, 'numeric');
            app.WindowLengthEditField.Value = 1024;
            app.OverlapEditFieldLabel = uilabel(app.SpectrogramParametersPanel);
            app.OverlapEditFieldLabel.Text = 'Overlap';
            app.OverlapEditField = uieditfield(app.SpectrogramParametersPanel, 'numeric');
            app.OverlapEditField.Value = 0.5;
            app.FFTLengthEditFieldLabel = uilabel(app.SpectrogramParametersPanel);
            app.FFTLengthEditFieldLabel.Text = 'FFT Length';
            app.FFTLengthEditField = uieditfield(app.SpectrogramParametersPanel, 'numeric');
            app.FFTLengthEditField.Value = 1024;
            app.WindowTypeListBoxLabel = uilabel(app.SpectrogramParametersPanel);
            app.WindowTypeListBoxLabel.Text = 'Window Type';
            app.WindowTypeListBox = uilistbox(app.SpectrogramParametersPanel);
            app.WindowTypeListBox.Items = {'Hamming', 'Rectangular'};
            app.WindowTypeListBox.Value = 'Hamming';
            app.ClimsEditFieldLabel = uilabel(app.SpectrogramParametersPanel);
            app.ClimsEditFieldLabel.Text = 'Clims';
            app.ClimsEditField = uieditfield(app.SpectrogramParametersPanel, 'numeric');
            app.ClimsEditField.Value = -40;
            app.TransmissionParametersPanel = uipanel(app.UIFigure);
            app.TransmissionParametersPanel.Title = 'Transmission Parameters';
            app.CarrierFrequencyEditFieldLabel = uilabel(app.TransmissionParametersPanel);
            app.CarrierFrequencyEditFieldLabel.Text = 'Carrier Frequency';
            app.CarrierFrequencyEditField = uieditfield(app.TransmissionParametersPanel, 'numeric');
            app.CarrierFrequencyEditField.Value = 40000;
            app.SamplingFrequencyEditFieldLabel = uilabel(app.TransmissionParametersPanel);
            app.SamplingFrequencyEditFieldLabel.Text = 'Sampling Frequency';
            app.SamplingFrequencyEditField = uieditfield(app.TransmissionParametersPanel, 'numeric');
            app.SamplingFrequencyEditField.Value = 192000;
            app.RecordButton = uibutton(app.TransmissionParametersPanel, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.RecordingtimeEditFieldLabel = uilabel(app.TransmissionParametersPanel);
            app.RecordingtimeEditFieldLabel.Text = 'Recording time';
            app.RecordingtimeEditField = uieditfield(app.TransmissionParametersPanel, 'numeric');
            app.RecordingtimeEditField.Value = 10;
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UIAxes2 = uiaxes(app.Tab2);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.UIAxes3 = uiaxes(app.Tab3);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Tab4';
            app.UIAxes4 = uiaxes(app.Tab4);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.RadarDatasetProcessorLabel = uilabel(app.UIFigure);
            app.RadarDatasetProcessorLabel.Text = 'Radar Dataset Processor';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Dataset_Processor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end